classdef audio_filter_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        LowPassCutoffHzLabel  matlab.ui.control.Label
        FrequencyCutoff       matlab.ui.control.EditField
        PlayAudioButton       matlab.ui.control.Button
        RightPanel            matlab.ui.container.Panel
        FrequencyPlot         matlab.ui.control.UIAxes
        TimeSeriesPlot        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        audioTimeSeries; % The time series data from the audio file
        Fs; % The sampling frequency
        lowPassCutoff;
        filteredTimeSeries;
    end
    
    methods (Access = private)
        
        function loadAudio(app)
            [app.audioTimeSeries, app.Fs] = audioread("voice_memo.m4a");
            app.filteredTimeSeries = app.audioTimeSeries;
            displayTimeSeriesData(app);
            displayFrequencySpectrum(app);      
        end
        
        function displayTimeSeriesData(app)
            timeLen = length(app.filteredTimeSeries) / app.Fs;
            timeVec = 0:1/app.Fs:timeLen;
            timeVec = timeVec(1:length(timeVec)-1);
            
            plot(app.TimeSeriesPlot, timeVec, app.filteredTimeSeries);
            xlim(app.TimeSeriesPlot, [0 timeLen]);
        end
        
        function displayFrequencySpectrum(app)
            hz = (0:app.Fs);
            freqData = fft(app.filteredTimeSeries,length(hz));
            frequencyPlotCutoff = 3600; % hz to cut plotting off at, only interested in voice range
            
            plot(app.FrequencyPlot, hz,abs(freqData));
            xlim(app.FrequencyPlot,[0 frequencyPlotCutoff]);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            loadAudio(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {141, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            % Play the audio file
            soundsc(app.filteredTimeSeries,app.Fs);
        end

        % Value changed function: FrequencyCutoff
        function FrequencyCutoffValueChanged(app, event)
            app.lowPassCutoff = str2double(app.FrequencyCutoff.Value);
            
            fcutoff = app.lowPassCutoff;
            transw  = .25;
            order   = round( 19*(app.Fs)/fcutoff );
            
            shape   = [ 1 1 0 0 ];
            frex    = [ 0 fcutoff fcutoff+fcutoff*transw app.Fs/2 ] / (app.Fs/2);
            
            % filter kernel
            lowpassFiltkern = firls(order,frex,shape);
            app.filteredTimeSeries = filtfilt(lowpassFiltkern,1,app.audioTimeSeries);
            
            displayTimeSeriesData(app);
            displayFrequencySpectrum(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PlayAudioButton = uibutton(app.LeftPanel, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.FrequencyCutoff = uieditfield(app.LeftPanel, 'text');
            app.FrequencyCutoff.ValueChangedFcn = createCallbackFcn(app, @FrequencyCutoffValueChanged, true);
            app.LowPassCutoffHzLabel = uilabel(app.LeftPanel);
            app.LowPassCutoffHzLabel.Text = 'Low Pass Cutoff (Hz)';
            app.RightPanel = uipanel(app.GridLayout);
            app.TimeSeriesPlot = uiaxes(app.RightPanel);
            title(app.TimeSeriesPlot, 'Sample Time Series')
            xlabel(app.TimeSeriesPlot, 'Time')
            ylabel(app.TimeSeriesPlot, 'Amplitude')
            zlabel(app.TimeSeriesPlot, 'Z')
            app.FrequencyPlot = uiaxes(app.RightPanel);
            title(app.FrequencyPlot, 'Frequency Spectrum')
            xlabel(app.FrequencyPlot, 'Hz')
            ylabel(app.FrequencyPlot, 'Amplitude')
            zlabel(app.FrequencyPlot, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audio_filter_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end